classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LiveViewButton               matlab.ui.control.Button
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        zEditFieldLabel              matlab.ui.control.Label
        zEditField                   matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        Button2                      matlab.ui.control.Button
        yawEditFieldLabel            matlab.ui.control.Label
        yawEditField                 matlab.ui.control.NumericEditField
        rollEditFieldLabel           matlab.ui.control.Label
        rollEditField                matlab.ui.control.NumericEditField
        pitchEditFieldLabel          matlab.ui.control.Label
        pitchEditField               matlab.ui.control.NumericEditField
        EnableSpeachButton           matlab.ui.control.Button
        GotoPositionLabel            matlab.ui.control.Label
        TeleoperationButton          matlab.ui.control.Button
        GoLiveButton                 matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        DemonstrateTrajectoryButton  matlab.ui.control.Button
        GoHomeButton                 matlab.ui.control.Button
        CalibrateButton              matlab.ui.control.Button
        TasknameEditFieldLabel       matlab.ui.control.Label
        TasknameEditField            matlab.ui.control.EditField
        ExecuteTaskButton            matlab.ui.control.Button
        SendLastButton               matlab.ui.control.Button
        RecordTimeEditFieldLabel     matlab.ui.control.Label
        RecordTimeEditField          matlab.ui.control.NumericEditField
        LoadTrajectoryButton         matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
    end


    properties (Access = public)
        CData % Description
        Robot % Description
        Teleop
        Joints % Description
        ShowRobot
        show_robot_sub
        Live
        Moving
        Goal = [0 0 0 0 0 0]
        Threshold = [0 0 0 0 0 0]+pi/180*5;
        Task
    end

    methods (Access = public)
    
        function JointCallBack(app,~,~)
            if mod(app.ShowRobot,2)==1
            show(app.Robot.robot,app.Robot.Configuration); 
            if ~isempty(app.Robot.Teleop)
            hold on
            scatter3(app.Robot.Teleop.Position(4),app.Robot.Teleop.Position(5),app.Robot.Teleop.Position(6))
            hold off
            end

            end
            if ~isempty(app.Robot.Teleop)
                if app.Live == 1 & app.Moving ==0
                    app.Goal = app.Robot.Teleop.Position;
                    app.Robot.GoTo(app.Robot.Teleop.Position)
                    app.Moving = 1;
                elseif ~any(abs(app.Robot.Position-app.Robot.Goal) > app.Threshold)
                        app.Moving = 0;
                end
            end
                
        
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create robot object 
            app.Robot = ABB_Robot();
            app.show_robot_sub = rossubscriber('/joint_states',@app.JointCallBack);
            app.ShowRobot = 0;
            app.Live = 0;
            app.Moving =0;
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            app.Robot.GoTo([app.rollEditField.Value,app.pitchEditField.Value,app.yawEditField.Value,app.xEditField.Value,app.yEditField.Value,app.zEditField.Value])
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.Robot.Teleop.Calibrate
        end

        % Button pushed function: DemonstrateTrajectoryButton
        function DemonstrateTrajectoryButtonPushed(app, event)
            % To run this, you need to fisrt enable teloperation. The new task name specified in Task name will be added to the Tasks.mat file.
            f = figure(2);
            pause(0.1);
            temp = app.ShowRobot;
            app.ShowRobot = 0;
            hold on
            app.Robot.Demo = [];
            tic
            % record app.Robot.Teleop.Position for specified time and add it to Robot.Demo
            while toc<app.RecordTimeEditField.Value
            receive(app.Robot.Teleop.upper_arm_sub);    
            receive(app.Robot.Teleop.lower_arm_sub); 
            scatter3(app.Robot.Teleop.Position(4),app.Robot.Teleop.Position(5),app.Robot.Teleop.Position(6))
            app.Robot.Demo = [app.Robot.Demo;app.Robot.Teleop.Position];
            end
            app.Robot.Demo = [[1:length(app.Robot.Demo(:,1))]'./length(app.Robot.Demo(:,1))*toc app.Robot.Demo];
            delete(f)
            figure(1)
            app.ShowRobot = temp;
            app.Robot.Learn_Task(app.TasknameEditField.Value) % learn the trajectory stored in Robot.Demo
            Tasks = app.Robot.Tasks;
            save('Tasks','Tasks');
            app.UITable.Data = {[];[];[];[];[]};
            names = fieldnames(app.Robot.Tasks);
            for i = 1:length(names)
            app.UITable.Data{i} =names{i};
            end
            
        end

        % Button pushed function: EnableSpeachButton
        function EnableSpeachButtonPushed(app, event)
            if app.Robot.Listening ==0
                app.Robot.StartListening
            elseif app.Robot.Listening ==1
                app.Robot.StopListening
            end
        end

        % Button pushed function: ExecuteTaskButton
        function ExecuteTaskButtonPushed(app, event)
            T = app.UITable.Data;
            s = size(app.UITable.Data);
            for i = 1:s(1)
                if strcmp(app.UITable.Data{i,1},app.Task)
                    row = i;
                end
            end
            %             keyboard
            S = ones(1,6);
            T = ones(1,6);
            for j = 3:8
            S(j-2) = app.UITable.Data{row,j};
            end
            S(6) = S(6)-.187;
            T = T*app.UITable.Data{row,2};
            app.Robot.Execute_Task((app.Task),T,S)
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            app.Robot.Home
        end

        % Button pushed function: GoLiveButton
        function GoLiveButtonPushed(app, event)
            if app.Live == 0
                app.Live = 1;
            elseif app.Live ==1
                app.Live = 0;
            end
        end

        % Button pushed function: LiveViewButton
        function LiveViewButtonPushed(app, event)
            
            app.ShowRobot =app.ShowRobot+1;
            %             app.ShowRobot{1} = 1;
            
        end

        % Button pushed function: LoadTrajectoryButton
        function LoadTrajectoryButtonPushed(app, event)
            load('Tasks.mat')
            app.Robot.Tasks = Tasks;
            app.UITable.Data = {[];[];[];[];[];[];[];[];[];[]};
            names = fieldnames(app.Robot.Tasks);
            for i = 1:length(names)
            app.UITable.Data{i} =[names{i} 0 0 0 0];
            for j = 2:8
            app.UITable.Data{i,j} = 0;
            end
             set(app.UITable,'ColumnEditable',logical(1));
            end
        end

        % Button pushed function: SendLastButton
        function SendLastButtonPushed(app, event)
            app.Robot.SendLast
        end

        % Button pushed function: TeleoperationButton
        function TeleoperationButtonPushed(app, event)
            if isempty(app.Robot.Teleop)
            app.Robot.Teleop = Teleoperator();
            pause(0.2)
            else
               app.Robot.Teleop = []; 
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
           app.Task = event.Source.Data{event.Indices(1)};
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LiveViewButton = uibutton(app.UIFigure, 'push');
            app.LiveViewButton.ButtonPushedFcn = createCallbackFcn(app, @LiveViewButtonPushed, true);
            app.LiveViewButton.Text = 'Live View';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.Value = 0.3;
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Value = 0.5;
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.yawEditFieldLabel = uilabel(app.UIFigure);
            app.yawEditFieldLabel.Text = 'yaw';
            app.yawEditField = uieditfield(app.UIFigure, 'numeric');
            app.rollEditFieldLabel = uilabel(app.UIFigure);
            app.rollEditFieldLabel.Text = 'roll';
            app.rollEditField = uieditfield(app.UIFigure, 'numeric');
            app.pitchEditFieldLabel = uilabel(app.UIFigure);
            app.pitchEditFieldLabel.Text = 'pitch';
            app.pitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.pitchEditField.Value = 90;
            app.EnableSpeachButton = uibutton(app.UIFigure, 'push');
            app.EnableSpeachButton.ButtonPushedFcn = createCallbackFcn(app, @EnableSpeachButtonPushed, true);
            app.EnableSpeachButton.Text = 'Enable Speach';
            app.GotoPositionLabel = uilabel(app.UIFigure);
            app.GotoPositionLabel.Text = 'Go to Position:';
            app.TeleoperationButton = uibutton(app.UIFigure, 'push');
            app.TeleoperationButton.ButtonPushedFcn = createCallbackFcn(app, @TeleoperationButtonPushed, true);
            app.TeleoperationButton.Text = 'Teleoperation';
            app.GoLiveButton = uibutton(app.UIFigure, 'push');
            app.GoLiveButton.ButtonPushedFcn = createCallbackFcn(app, @GoLiveButtonPushed, true);
            app.GoLiveButton.Text = 'Go Live';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Trajectory'; 'Time'; 'Apha'; 'Beta'; 'Gamma'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DemonstrateTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.DemonstrateTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DemonstrateTrajectoryButtonPushed, true);
            app.DemonstrateTrajectoryButton.Text = 'Demonstrate Trajectory';
            app.GoHomeButton = uibutton(app.UIFigure, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Text = 'Go Home';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.TasknameEditFieldLabel = uilabel(app.UIFigure);
            app.TasknameEditFieldLabel.Text = 'Task name';
            app.TasknameEditField = uieditfield(app.UIFigure, 'text');
            app.ExecuteTaskButton = uibutton(app.UIFigure, 'push');
            app.ExecuteTaskButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteTaskButtonPushed, true);
            app.ExecuteTaskButton.Text = 'Execute Task';
            app.SendLastButton = uibutton(app.UIFigure, 'push');
            app.SendLastButton.ButtonPushedFcn = createCallbackFcn(app, @SendLastButtonPushed, true);
            app.SendLastButton.Text = 'Send Last';
            app.RecordTimeEditFieldLabel = uilabel(app.UIFigure);
            app.RecordTimeEditFieldLabel.Text = 'Record Time';
            app.RecordTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.LoadTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrajectoryButtonPushed, true);
            app.LoadTrajectoryButton.Text = 'Load Trajectory';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end